classdef LogicSimulator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainWindow           matlab.ui.Figure
        FileMenu             matlab.ui.container.Menu
        NewMenu              matlab.ui.container.Menu
        OpenMenu             matlab.ui.container.Menu
        SaveMenu             matlab.ui.container.Menu
        SaveAsMenu           matlab.ui.container.Menu
        GridLayout           matlab.ui.container.GridLayout
        GridLayout6          matlab.ui.container.GridLayout
        GridLayout7          matlab.ui.container.GridLayout
        ReevaluateButton     matlab.ui.control.Button
        ResultLabel          matlab.ui.control.Label
        ResultTruthTable     matlab.ui.control.Table
        GridLayout2          matlab.ui.container.GridLayout
        GridLayout5          matlab.ui.container.GridLayout
        ComponentsListBox    matlab.ui.control.ListBox
        Label                matlab.ui.control.Label
        GridLayout4          matlab.ui.container.GridLayout
        ActionDeleteButton   matlab.ui.control.Button
        ActionMoveButton     matlab.ui.control.Button
        ActionsLabel         matlab.ui.control.Label
        GridLayout3          matlab.ui.container.GridLayout
        SelectionTruthTable  matlab.ui.control.Table
        TruthTableLabel      matlab.ui.control.Label
        SelectionLabel       matlab.ui.control.Label
        ModeSelection        matlab.ui.container.ButtonGroup
        ModeSelectionPlace   matlab.ui.control.RadioButton
        ModeSelectionSelect  matlab.ui.control.RadioButton
        CircuitPlot          matlab.ui.control.UIAxes
    end


    properties (Access = private)
        % The logic circuit
        circuit = CircuitManager();

        % the start of a link currently being drawn
        % this has to be stored here as, unlike all other components, links
        % require two clicks
        % [-1, -1] is used to signify that there is no current link start -
        % this is suitable as link end coordinates are snapped to the
        % nearest even coordinates
        link_start = [-1, -1];

        % list of inputs and outputs (referenced using their component ids)
        inputs = [];
        outputs = [];
        
        % the ID of the currently selected component/link, and whether it's
        % a component or a link
        currently_selected = 0;
        currently_selected_type = "";
        % move flag for signifying if the user is currently moving a 
        % component
        move_action_active = false;

        % the name of the current file, and its path
        filename = "";
        filepath = "";
        
        % file filter to use for file selection dialog
        file_filter = { ...
            '*.lsim', "LogicSimulator Circuit (*.lsim)"; ...
            '*.*', "All Files (*.*)" ...
        };
    end
    
    methods (Access = private)
        
        function reset_link_start(app)
            %RESET_LINK_START 
            %Resets the start point of the current link and redraws the 
            %circuit to remove any link start marks

            app.link_start = [-1, -1];
            hold(app.CircuitPlot, "off");
            app.draw_circuit();
        end
        
        function add_link(app, position_clicked)
            %ADD_LINK
            %Attempts to start/finish a link, and displays an alert to the
            %user if they've attempted something invalid

            if app.link_start == [-1, -1]
                % a link is not currently in the process of being added, so
                % set the link start point and plot a star to indicate to
                % the user the link start point

                app.link_start = position_clicked;
                plot(app.CircuitPlot, position_clicked(1), position_clicked(2), "r*");
                hold(app.CircuitPlot, "on");
                app.circuit.draw(app.CircuitPlot);
            else
                % a link start point has already been selected, so attempt
                % to add the link

                % get the components and pins from the
                % positions
                [component_1, pin_1, is_input_1] = app.circuit.get_component_and_pin_from_position(app.link_start);
                [component_2, pin_2, is_input_2] = app.circuit.get_component_and_pin_from_position(position_clicked);
                            
                % check the link actually connects two pins
                if component_1 == 0 || component_2 == 0
                     uialert(app.MainWindow, "Links must go between two pins; they cannot be open on either end", "Problem creating link");
                     % reset link start
                     app.reset_link_start();
                     return;
                end

                % check the user isn't trying to connect two
                % inputs or two outputs together
                if is_input_1 == is_input_2
                    uialert(app.MainWindow, "Links cannot go between two output pins or two input pins", "Problem creating link");
                    app.reset_link_start();
                    return;
                end

                % add the link to the circuit, making sure to get the input
                % and output end of the link the right way round
                if is_input_1 == true
                    app.circuit.add_link(component_2, pin_2, component_1, pin_1);
                else
                    app.circuit.add_link(component_1, pin_1, component_2, pin_2);
                end
                
                app.reset_link_start();
            end
        end
        
        function input = generate_all_input_possibilities(app, num_inputs)
            %GENERATE_ALL_INPUT_POSSIBILITIES
            %Recursively generates the complete input space for a binary 
            %input system
            %e.g. generate_all_input_possibilities(2) would return
            %⎡0 0⎤ 
            %⎢0 1⎥
            %⎢1 0⎥
            %⎣1 1⎦

            if num_inputs == 1
                input = [
                    0;
                    1;
                ];
                return;
            end
            input_one_layer_down = app.generate_all_input_possibilities(num_inputs - 1);
            input = [
                zeros(2^(num_inputs-1), 1), input_one_layer_down;
                ones( 2^(num_inputs-1), 1),  input_one_layer_down;
            ];
        end
        
        function draw_circuit(app)
            %DRAW_CIRCUIT
            %Draws the circuit in app.circuit to the circuit plot

            % if nothing is to be drawn, reset the axes, as this only
            % happens when something is deleted and since there's nothing 
            % left to be drawn, the plot won't be cleared by the rest of
            % the function, so it must be done manually.
            if isempty(app.circuit.components)
                app.reset_circuit_plot();
                return;
            end

            % draw the main circuit
            app.circuit.draw(app.CircuitPlot);
            hold(app.CircuitPlot, "on");
            
            % draw the currently selected item again in green to highlight
            % it
            if app.currently_selected_type == "component"
                app.circuit.components{app.currently_selected}.draw(app.CircuitPlot, "g");
            elseif app.currently_selected_type == "link"
                link = app.circuit.links(app.currently_selected);
                app.circuit.draw_link(app.CircuitPlot, link, "g");
            end

            hold(app.CircuitPlot, "off");
        end

        function reset_circuit_plot(app)
            %RESET_CIRCUIT_PLOT
            %Resets the circuit plot axes by clearing anything plotted and
            %resetting the axis and plot properties

            cla(app.CircuitPlot);
            axis(app.CircuitPlot, [0 100 -40 40]);
            axis(app.CircuitPlot, "equal");
            app.CircuitPlot.Interactions = [];
        end
        
        function clear_selection(app)
            %CLEAR_SELECTION
            %Clears the current component/link selection

            app.currently_selected = 0;
            app.currently_selected_type = "";

            app.SelectionTruthTable.Visible = false;
            app.ActionMoveButton.Enable = false;
            app.ActionDeleteButton.Enable = false;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % initialise the circuit plot
            app.reset_circuit_plot();

            % Set up selection menu for no selection:
            % remove truth table and disable all actions
            app.SelectionTruthTable.Visible = false;
            app.ActionMoveButton.Enable = false;
            app.ActionDeleteButton.Enable = false;
        end

        % Button down function: CircuitPlot
        function CircuitPlotButtonDown(app, event)
            % get the position of the mouse click
            position = event.IntersectionPoint(1:2)';

            % snap clicks to the grid for usability (nearest
            % even ordinate)
            position = round(position / 2) * 2;

            if event.Button == 1    % left click
                if app.move_action_active == true
                    % the user is currently moving a gate
                    
                    % reset the move action flag
                    app.move_action_active = false;
                    % update the component's position
                    app.circuit.components{app.currently_selected}.position = position;
                    
                    app.clear_selection();
                    app.draw_circuit();

                elseif app.ModeSelection.SelectedObject.Text == "Place"
                    
                    % add the correct component to the circuit
                    if app.ComponentsListBox.Value == "Link"
                        app.add_link(position);
                        return;
                    end
                    if app.link_start ~= [-1, -1]
                        % the user no longer wants a link, but would be
                        % confused if the link starting point they just
                        % selected were used for a link in the future, so
                        % it should be cleared
                        app.reset_link_start();
                    end
                    if app.ComponentsListBox.Value == "Input"
                        id = app.circuit.add_component(Input(position, sprintf("in%d", length(app.inputs) + 1)));
                        app.inputs = [app.inputs, id];
                    elseif app.ComponentsListBox.Value == "Output"
                        id = app.circuit.add_component(Output(position, sprintf("out%d", length(app.outputs) + 1)));
                        app.outputs = [app.outputs, id];
                    elseif app.ComponentsListBox.Value == "NOT gate"
                        app.circuit.add_component(NotGate(position));
                    elseif app.ComponentsListBox.Value == "AND gate"
                        app.circuit.add_component(AndGate(position));
                    elseif app.ComponentsListBox.Value == "OR gate"
                        app.circuit.add_component(OrGate(position));
                    elseif app.ComponentsListBox.Value == "XOR gate"
                        app.circuit.add_component(XorGate(position));
                    elseif app.ComponentsListBox.Value == "NAND gate"
                        app.circuit.add_component(NandGate(position));
                    elseif app.ComponentsListBox.Value == "NOR gate"
                        app.circuit.add_component(NorGate(position));
                    elseif app.ComponentsListBox.Value == "XNOR gate"
                        app.circuit.add_component(XnorGate(position));
                    end
                    
                    app.draw_circuit();
                    
                elseif app.ModeSelection.SelectedObject.Text == "Select"
                    % get the type and id of the component at the selected
                    % position
                    [app.currently_selected_type, app.currently_selected] ...
                        = app.circuit.get_type_and_id_of_clicked_object(position);

                    app.draw_circuit();
                    
                    % update the selection menu
                    if app.currently_selected_type == "component"
                        % make truth table visible and enable move and
                        % delete actions
                        app.SelectionTruthTable.Visible = true;
                        app.ActionMoveButton.Enable = true;
                        app.ActionDeleteButton.Enable = true;

                        % set the truth table
                        component_type = app.circuit.components{app.currently_selected}.type_printable;
                        app.SelectionTruthTable.ColumnName = ["In 1", "In 2", "Out 1"];
                        if component_type == "input" || component_type == "output"
                            app.SelectionTruthTable.Visible = false;
                        elseif component_type == "not" 
                            app.SelectionTruthTable.ColumnName = ["In 1", "Out 1"];
                            app.SelectionTruthTable.Data = [
                                0 1;
                                1 0;
                            ];
                        elseif component_type == "and"
                            app.SelectionTruthTable.Data = [
                                0 0 0;
                                0 1 0;
                                1 0 0;
                                1 1 1;
                            ];
                        elseif component_type == "or"
                            app.SelectionTruthTable.Data = [
                                0 0 0;
                                0 1 1;
                                1 0 1;
                                1 1 1;
                            ];
                        elseif component_type == "xor"
                            app.SelectionTruthTable.Data = [
                                0 0 0;
                                0 1 1;
                                1 0 1;
                                1 1 0;
                            ];
                        elseif component_type == "annd"
                            app.SelectionTruthTable.Data = [
                                0 0 1;
                                0 1 1;
                                1 0 1;
                                1 1 0;
                            ];
                        elseif component_type == "nor"
                            app.SelectionTruthTable.Data = [
                                0 0 1;
                                0 1 0;
                                1 0 0;
                                1 1 0;
                            ];
                        elseif component_type == "xnor"
                            app.SelectionTruthTable.Data = [
                                0 0 1;
                                0 1 0;
                                1 0 0;
                                1 1 1;
                            ];
                        end

                    elseif app.currently_selected_type == "link"
                        % remove truth table, enable delete action,
                        % disable move action
                        app.SelectionTruthTable.Visible = false;
                        app.ActionMoveButton.Enable = false;
                        app.ActionDeleteButton.Enable = true;

                    else
                        % nothing was selected/selection was cleared
                        % remove truth table and disable all actions
                        app.SelectionTruthTable.Visible = false;
                        app.ActionMoveButton.Enable = false;
                        app.ActionDeleteButton.Enable = false;

                    end
                end
            end
        end

        % Button pushed function: ReevaluateButton
        function ReevaluateButtonPushed(app, event)
            % set up the result truth table headings using the labels of
            % the input and output components
            input_headings = strings(1, length(app.inputs));
            for input = enumerate(app.inputs)
                input_headings(input.index) = app.circuit.components{input.value}.label;
            end
            output_headings = strings(1, length(app.outputs));
            for output = enumerate(app.outputs)
                output_headings(output.index) = app.circuit.components{output.value}.label;
            end
            app.ResultTruthTable.ColumnName = [input_headings, output_headings];

            % initialise the truth table with all zeroes
            truth_table = zeros(2^length(app.inputs), length(app.inputs) + length(app.outputs));

            % go through each possible combination of inputs, and evaluate
            % the circuit for it
            input_combinations = app.generate_all_input_possibilities(length(app.inputs));
            for row = 1:size(input_combinations, 1)
                % set all the inputs to the requisite values
                current_combination = input_combinations(row, :);
                for input = 1:length(app.inputs)
                    app.circuit.components{app.inputs(input)}.input = current_combination(input);
                end
                
                % evaluate the circuit

                % updating once for each of the components means that at
                % the end, even in the worst case, the new state will be
                % the final one (for steady-state circuits)
                for i = 1:length(app.circuit.components)
                    app.circuit.update()
                end
                
                % fill in the outputs
                current_output = zeros(1, length(app.outputs));
                for output = 1:length(app.outputs)
                    current_output(output) = app.circuit.components{app.outputs(output)}.output;
                end

                % add the data for this evaluation round to the table data
                truth_table(row, :) = [current_combination, current_output];
            end

            % put the truth table data into the displayed truth table
            app.ResultTruthTable.Data = truth_table;
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            if app.filename == ""
                % the file is as-yet unsaved, so the user must choose
                % where and what to save it as
                app.SaveAsMenuSelected(event);
                return;
            end
            
            app.circuit.serialise(app.filepath + app.filename);
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            % prompt the user to select a file to open
            [name, path] = uigetfile(app.file_filter, "Open File");

            if name == 0
                % the user aborted, so return
                return;
            end

            % convert name and path from char arrays to strings
            app.filename = string(name);
            app.filepath = string(path);
                
            % update the window title to use the new filename
            app.MainWindow.Name = sprintf("LogicSimulator - %s", app.filename);
            
            % load the circuit from the file
            app.circuit.deserialise(app.filepath + app.filename);

            % reset the lists of inputs and outputs
            app.inputs = [];
            app.outputs = [];
            for index = 1:length(app.circuit.components)
                type = app.circuit.components{index}.get_type_printable();
                if type == "input"
                    app.inputs = [app.inputs, index];
                elseif type == "output"
                    app.outputs = [app.outputs, index];
                end
            end

            % draw the newly loaded circuit
            app.circuit.draw(app.CircuitPlot);
        end

        % Menu selected function: SaveAsMenu
        function SaveAsMenuSelected(app, event)
            % prompt the user to select/create a file to save to
            [name, path] = uiputfile(app.file_filter, "Save File Name");

            if name == 0
                % the user aborted
                return;
            end
                
            % convert name and path from char arrays to strings
            app.filename = string(name);
            app.filepath = string(path);
                
            % update the window title to use the filename
            app.MainWindow.Name = sprintf("LogicSimulator - %s", app.filename);

            % save the circuit to the file
            app.circuit.serialise(app.filepath + app.filename);
        end

        % Button pushed function: ActionDeleteButton
        function ActionDeleteButtonPushed(app, event)
            if app.currently_selected_type == "component"
                app.circuit.remove_component(app.currently_selected);
                
                % remove all links going to the component's input and
                % output pins and adjust the numbering of the others where
                % necessary so they all still point to the correct pins
                for index = length(app.inputs):-1:1
                    if app.inputs(index) == app.currently_selected
                        app.inputs(index) = [];
                    elseif app.inputs(index) > app.currently_selected
                        app.inputs(index) = app.inputs(index) - 1;
                        app.circuit.components{app.inputs(index)}.label = sprintf("in%d", index - 1);
                    end
                end
                for index = length(app.outputs):-1:1
                    if app.outputs(index) == app.currently_selected
                        app.outputs(index) = [];
                    elseif app.outputs(index) > app.currently_selected
                        app.outputs(index) = app.outputs(index) - 1;
                        app.circuit.components{app.outputs(index)}.label = sprintf("in%d", index - 1);
                    end
                end

            elseif app.currently_selected_type == "link"
                app.circuit.remove_link(app.currently_selected);
            end

            app.clear_selection();
            app.draw_circuit();
        end

        % Button pushed function: ActionMoveButton
        function ActionMoveButtonPushed(app, event)
            % set 'moving' flag
            app.move_action_active = true;
            % the rest of the move is carried out in the circuit click
            % callback
        end

        % Menu selected function: NewMenu
        function NewMenuSelected(app, event)
            % update the window title
            app.MainWindow.Name = "LogicSimulator - Untitled";

            % clear the circuit
            app.circuit = CircuitManager();

            % clear the inputs and outputs
            app.inputs = [];
            app.outputs = [];

            app.clear_selection();
            app.draw_circuit();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainWindow = uifigure('Visible', 'off');
            app.MainWindow.Name = 'LogicSimulator - Untitled';
            app.FileMenu = uimenu(app.MainWindow);
            app.FileMenu.Text = 'File';
            app.NewMenu = uimenu(app.FileMenu);
            app.NewMenu.MenuSelectedFcn = createCallbackFcn(app, @NewMenuSelected, true);
            app.NewMenu.Text = 'New';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Text = 'Save';
            app.SaveAsMenu = uimenu(app.FileMenu);
            app.SaveAsMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveAsMenuSelected, true);
            app.SaveAsMenu.Text = 'Save As';
            app.GridLayout = uigridlayout(app.MainWindow);
            app.CircuitPlot = uiaxes(app.GridLayout);
            app.CircuitPlot.XLim = [0 50];
            app.CircuitPlot.YLim = [-20 20];
            app.CircuitPlot.ButtonDownFcn = createCallbackFcn(app, @CircuitPlotButtonDown, true);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.ModeSelection = uibuttongroup(app.GridLayout2);
            app.ModeSelection.Title = 'Mode';
            app.ModeSelectionSelect = uiradiobutton(app.ModeSelection);
            app.ModeSelectionSelect.Text = 'Select';
            app.ModeSelectionSelect.Value = true;
            app.ModeSelectionPlace = uiradiobutton(app.ModeSelection);
            app.ModeSelectionPlace.Text = 'Place';
            app.SelectionLabel = uilabel(app.GridLayout2);
            app.SelectionLabel.Text = 'Selection';
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.TruthTableLabel = uilabel(app.GridLayout3);
            app.TruthTableLabel.Text = 'Truth Table';
            app.SelectionTruthTable = uitable(app.GridLayout3);
            app.SelectionTruthTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.SelectionTruthTable.RowName = {};
            app.GridLayout4 = uigridlayout(app.GridLayout2);
            app.ActionsLabel = uilabel(app.GridLayout4);
            app.ActionsLabel.Text = 'Actions';
            app.ActionMoveButton = uibutton(app.GridLayout4, 'push');
            app.ActionMoveButton.ButtonPushedFcn = createCallbackFcn(app, @ActionMoveButtonPushed, true);
            app.ActionMoveButton.Text = 'Move';
            app.ActionDeleteButton = uibutton(app.GridLayout4, 'push');
            app.ActionDeleteButton.ButtonPushedFcn = createCallbackFcn(app, @ActionDeleteButtonPushed, true);
            app.ActionDeleteButton.Text = 'Delete';
            app.GridLayout5 = uigridlayout(app.GridLayout2);
            app.Label = uilabel(app.GridLayout5);
            app.Label.Text = 'Components';
            app.ComponentsListBox = uilistbox(app.GridLayout5);
            app.ComponentsListBox.Items = {'Link', 'Input', 'Output', 'NOT gate', 'AND gate', 'OR gate', 'XOR gate', 'NAND gate', 'NOR gate', 'XNOR gate'};
            app.ComponentsListBox.Value = 'Link';
            app.GridLayout6 = uigridlayout(app.GridLayout);
            app.ResultTruthTable = uitable(app.GridLayout6);
            app.ResultTruthTable.ColumnName = '';
            app.ResultTruthTable.RowName = {};
            app.ResultTruthTable.Multiselect = 'off';
            app.GridLayout7 = uigridlayout(app.GridLayout6);
            app.ResultLabel = uilabel(app.GridLayout7);
            app.ResultLabel.Text = 'Result';
            app.ReevaluateButton = uibutton(app.GridLayout7, 'push');
            app.ReevaluateButton.ButtonPushedFcn = createCallbackFcn(app, @ReevaluateButtonPushed, true);
            app.ReevaluateButton.Text = 'Reevaluate';
            % Show the figure after all components are created
            app.MainWindow.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LogicSimulator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainWindow)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainWindow)
        end
    end
end